classdef main_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        InitialInputsTab           matlab.ui.container.Tab
        c1istheintegrationconstantinthefirstintegralofMxLabel  matlab.ui.control.Label
        EquationsLabel             matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        I_xK_xEditField_4          matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        I_xK_xEditField_3          matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        I_xK_xEditField_2          matlab.ui.control.NumericEditField
        I_xyK_xyEditField          matlab.ui.control.NumericEditField
        I_xyK_xyEditFieldLabel     matlab.ui.control.Label
        I_yK_yEditField            matlab.ui.control.NumericEditField
        I_yLabel                   matlab.ui.control.Label
        I_xK_xEditField            matlab.ui.control.NumericEditField
        I_xK_xLabel                matlab.ui.control.Label
        InertiaoptionsButtonGroup  matlab.ui.container.ButtonGroup
        UsingKxKyKxyButton         matlab.ui.control.RadioButton
        UsingIxIyIxyButton         matlab.ui.control.RadioButton
        SymbolicallyButton         matlab.ui.control.RadioButton
        BeamLengthEditField        matlab.ui.control.NumericEditField
        BeamLengthEditFieldLabel   matlab.ui.control.Label
        BeginButton                matlab.ui.control.Button
        ProblemInputTab            matlab.ui.container.Tab
        Panel_3                    matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        Panel_2                    matlab.ui.container.Panel
        ClearAllButton_2           matlab.ui.control.Button
        NewSupportButton           matlab.ui.control.Button
        SupportsPanel              matlab.ui.container.Panel
        Panel                      matlab.ui.container.Panel
        ClearAllButton             matlab.ui.control.Button
        NewLoadButton              matlab.ui.control.Button
        LoadsPanel                 matlab.ui.container.Panel
        GraphsTab                  matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        DeflectionLabel            matlab.ui.control.Label
        MomentLabel                matlab.ui.control.Label
        ShearLabel                 matlab.ui.control.Label
        XZPlaneLabel               matlab.ui.control.Label
        YZPlaneLabel               matlab.ui.control.Label
        SolveButton                matlab.ui.control.Button
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes7                    matlab.ui.control.UIAxes
        UIAxes8                    matlab.ui.control.UIAxes
        UIAxes9                    matlab.ui.control.UIAxes
        UIAxes10                   matlab.ui.control.UIAxes
        UIAxes5                    matlab.ui.control.UIAxes
        EquationsTab               matlab.ui.container.Tab
        UIAxes12                   matlab.ui.control.UIAxes
        UIAxes11                   matlab.ui.control.UIAxes
        CustomCalculatorTab        matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        PanelCount = 0;       % Counter to track the number of added panels
        PanelCount_2 = 0;       % Counter to track the number of added panels
        PanelHeight = 80;     % Height of each panel
        PanelSpacing = 10;    % Space between panels
        titles = {};
        titles2 = {};
        allpanels;
        allloads;
        isloadadded;
        allpanels2;
        deleted_n;
        deleted_n2;
        preview;
        prevstart = false;
        allplotsload;
        issuppadded;
        allsupps;
        allplotssupp;
        begin = false;
        isloadpanel = false;
        issupppanel = false;
        allbeamplots;
        Mx;My;Sx;Sy;v;v_dash;u;u_dash;solutions;
        solvedplots; 
        issolved = false % Description
    end
    
    methods (Access = private)
        
        function DropdownValueChanged(app, dropdown, panel)

            if contains(dropdown.Value,'Conc')
                panel.Children(1).Visible = 'off';
                panel.Children(2).Visible = 'off';
                panel.Children(5).Visible = 'off';
                panel.Children(6).Visible = 'off';
                if strcmpi(dropdown.Value,"Conc moment")
                    panel.Children(9).Items = ["CCW","CW"];
                else
                    panel.Children(9).Items = ["down","up"];
                end
            elseif dropdown.Value == "Distributed Load"
                panel.Children(1).Visible = 'on';
                panel.Children(2).Visible = 'on';
                panel.Children(5).Visible = 'on';
                panel.Children(6).Visible = 'on';
                panel.Children(9).Items = ["down","up"];
            
            end

        end

        function out = DropdownValueChanged_S1(app, dropdown)

            if strcmp(dropdown.Value,'Fixed')
                out = 1;
            elseif strcmp(dropdown.Value,'Simple')
                out = 2;
            elseif strcmp(dropdown.Value,'Roller')
                out = 3;
            end

        end


        function deleteMethodtest(app,src,event,stp)
            splited_title = strsplit(stp.Title,"_");
            delete(stp);

            st = str2num(splited_title{2});
            if app.isloadadded.(app.titles{st})
                delete(app.allloads.(app.titles{st}).pl)
            end
            if st<app.PanelCount
                f1 = (app.isloadadded.(app.titles{st}) == true) && (app.isloadadded.(app.titles{st+1}) == false);
            else
                f1 = true;
            end

            for i = st:app.PanelCount-1
                c = i+1;
                current = app.allpanels.(app.titles{c});
                current.Position(2) = -90 + i * (app.PanelHeight*2 + app.PanelSpacing);
                current.Title = app.titles{i};
                app.isloadadded.(app.titles{i}) = app.isloadadded.(app.titles{c});
                app.allpanels.(app.titles{i}) = current;
                if app.isloadadded.(app.titles{i})
                    app.allloads.(app.titles{i}) = app.allloads.(app.titles{c});
                    app.allloads = rmfield(app.allloads,app.titles{c});
                end
            end
            if f1
                app.allloads = rmfield(app.allloads,app.titles{st});
            end
            app.isloadadded = rmfield(app.isloadadded,app.titles{app.PanelCount});
            app.allpanels = rmfield(app.allpanels,app.titles{app.PanelCount});
            app.titles{app.PanelCount} = {};
            app.PanelCount = app.PanelCount-1;
            if app.PanelCount == 0
                app.isloadpanel = false;
            end
        end

        function deleteMethod3(app,src,event,stp)
            
            splited_title = strsplit(stp.Title,"_");
            delete(stp);

            st = str2num(splited_title{2});

            if app.issuppadded.(app.titles2{st})
                delete(app.allsupps.(app.titles2{st}).pl)
            end
            if st<app.PanelCount_2
                f1 = (app.issuppadded.(app.titles2{st}) == true) && (app.issuppadded.(app.titles2{st+1}) == false);
                
            else
                f1 = true;
            end

            for i = st:app.PanelCount_2-1
                c = i+1;
                current = app.allpanels2.(app.titles2{c});
                current.Position(2) = -90 + i * (app.PanelHeight*1.5 + app.PanelSpacing);
                current.Title = app.titles2{i};
                app.issuppadded.(app.titles2{i}) = app.issuppadded.(app.titles2{c});
                app.allpanels2.(app.titles2{i}) = current;
                if app.issuppadded.(app.titles2{i})
                    app.allsupps.(app.titles2{i}) = app.allsupps.(app.titles2{c});
                    AddMethod2(app,src,event,app.allpanels2.(app.titles2{i}));
                    app.allsupps = rmfield(app.allsupps,app.titles2{c});
                end
            end

            if f1
                app.allsupps = rmfield(app.allsupps,app.titles2{st});
            end

            app.issuppadded = rmfield(app.issuppadded,app.titles2{app.PanelCount_2});
            app.allpanels2 = rmfield(app.allpanels2,app.titles2{app.PanelCount_2});
            app.titles2{app.PanelCount_2} = {};
            app.PanelCount_2 = app.PanelCount_2 - 1;

            if app.PanelCount_2 == 0
                app.issupppanel = false;
            end
        end

        function prevMethod(app,src,event,panel)
            if app.prevstart
                delete(app.preview.pl)
            end
            app.prevstart = true;
            dir = panel.Children(9).Value;
            plane = panel.Children(10).Value;
            type = panel.Children(11).Value;
            p1 = panel.Children(7).Value;
            p2 = panel.Children(5).Value;
            m1 = panel.Children(3).Value;
            m2 = panel.Children(1).Value;
            if strcmpi(plane,'Y-Z')
                plane_n = 1;
            else
                plane_n = 2;
            end
            if strcmpi(type,'Conc force')
                app.preview = con_f(p1,m1,dir,plane_n,true);
            elseif strcmpi(type,'Conc moment')
                app.preview = con_m(p1,m1,dir,plane_n,true);
            else
                if (p1<p2 && m1==0) || (p1>p2 && m2==0)
                    %ntri
                    app.preview = n_tri(min(p1,p2),max(p1,p2),max(m1,m2),plane_n,false);
                elseif (p1<p2 && m2==0) || (p1>p2 && m1==0)
                    %rtri
                    app.preview = rev_tri(min(p1,p2),max(p1,p2),max(m1,m2),plane_n,false);
                elseif  m1==m2
                    %rec
                    app.preview = rec(min(p1,p2),max(p1,p2),m1,plane_n,false);
                elseif m2~=m1
                    %trap
                    if p1<p2
                        p_i = p1;
                        v1 = m1;
                        pf = p2;
                        v2 = m2;
                    else
                        p_i = p2;
                        v1 = m2;
                        pf = p1;
                        vf = m1;
                    end
                    app.preview = trap(p_i,pf,v1,v2,plane_n,false);
                end
            end
        end

        function AddMethod(app,src,event,panel)
            
            splited_title = strsplit(panel.Title,"_");

            n = str2num(splited_title{2});
            if app.isloadadded.(app.titles{n})
                z = app.allloads.(app.titles{n});
                delete(z.pl)
            end

            app.isloadadded.(app.titles{n}) = true;

            
            dir = panel.Children(9).Value;
            plane = panel.Children(10).Value;
            type = panel.Children(11).Value;
            p1 = panel.Children(7).Value;
            p2 = panel.Children(5).Value;
            m1 = panel.Children(3).Value;
            m2 = panel.Children(1).Value;
            if strcmpi(plane,'Y-Z')
                plane_n = 1;
            else
                plane_n = 2;
            end
            if strcmpi(type,'Conc force')
                added = con_f(p1,m1,dir,plane_n,false);
            elseif strcmpi(type,'Conc moment')
                added = con_m(p1,m1,dir,plane_n,false);
                close(added.f)
            else
                if strcmpi(dir,"up")
                    m1 = -m1;
                    m2 = -m2;
                end
                if (p1<p2 && m1==0) || (p1>p2 && m2==0)
                    %ntri
                    if m1 == 0
                        mn = m2;
                    else
                        mn = m1;
                    end
                    added = n_tri(min(p1,p2),max(p1,p2),mn,plane_n);
                elseif (p1<p2 && m2==0) || (p1>p2 && m1==0)
                    %rtri
                    if m1 == 0
                        mn = m2;
                    else
                        mn = m1;
                    end
                    added = rev_tri(min(p1,p2),max(p1,p2),mn,plane_n);
                elseif  m1==m2
                    %rec
                    added = rec(min(p1,p2),max(p1,p2),m1,plane_n);
                elseif m2~=m1
                    %trap
                    if p1<p2
                        p_i = p1;
                        v1 = m1;
                        pf = p2;
                        v2 = m2;
                    else
                        p_i = p2;
                        v1 = m2;
                        pf = p1;
                        vf = m1;
                    end
                    added = trap(p_i,pf,v1,v2,plane_n);
                end
            end
            app.allloads.(app.titles{n}) = added;
            app.allplotsload = [app.allplotsload added.pl];
        end


        function AddMethod2(app,src,event,panel)
            
            splited_title = strsplit(panel.Title,"_");

            n = str2num(splited_title{2});
            if app.issuppadded.(app.titles2{n})
                z = app.allsupps.(app.titles2{n});
                delete(z.pl)
            end

            app.issuppadded.(app.titles2{n}) = true;

            plane = panel.Children(1).Value;
            type = panel.Children(5).Value;
            p1 = panel.Children(3).Value;
            
            if strcmpi(plane,'Y-Z')
                plane_n = 1;
            else
                plane_n = 2;
            end
            if strcmpi(type,'fixed')
                added = fixed(p1,plane_n,n);
                close(added.f);
            elseif strcmpi(type,'simple')
                added = simple(p1,plane_n,n);
            elseif strcmpi(type,'roller')
                added = roller(p1,plane_n,n);
            end
            app.allsupps.(app.titles2{n}) = added;
            app.allplotssupp = [app.allplotssupp added.pl];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            app.UIFigure.WindowState = "maximized";
            global eb  w Is o yz_axes xz_axes Mx_axes My_axes Sx_axes Sy_axes v_axes u_axes prevaxes corraxes;
            o = 1;
            yz_axes = app.UIAxes;
            xz_axes = app.UIAxes2;
            prevaxes = app.UIAxes3;
            corraxes = app.UIAxes4;
            Sy_axes = app.UIAxes5;
            Mx_axes = app.UIAxes6;
            v_axes = app.UIAxes7;
            Sx_axes = app.UIAxes8;
            My_axes = app.UIAxes9;
            u_axes = app.UIAxes10;
            app.TabGroup.SelectedTab = app.InitialInputsTab;
            InertiaoptionsButtonGroupSelectionChanged(app);
            app.allloads.l    = 1; app.allloads    = rmfield(app.allloads   ,"l");
            app.allpanels.l   = 1; app.allpanels   = rmfield(app.allpanels  ,"l");
            app.allpanels2.l  = 1; app.allpanels2  = rmfield(app.allpanels2 ,"l");
            app.allsupps.l    = 1; app.allsupps    = rmfield(app.allsupps   ,"l");
            app.isloadadded.l = 1; app.isloadadded = rmfield(app.isloadadded,"l");
            app.issuppadded.l = 1; app.issuppadded = rmfield(app.issuppadded,"l");

        end

        % Button pushed function: NewLoadButton
        function NewLoadButtonPushed(app, event)
        if app.begin
            % Increment the panel count
            global eb; syms L; app.isloadpanel = true;
            app.PanelCount = app.PanelCount + 1;
            n = app.PanelCount;
            % Calculate the position for the new panel
            x = 10; % Left margin
            y = -90 + app.PanelCount * (app.PanelHeight*2 + app.PanelSpacing); % Dynamic vertical position
            width = 400; % Width of the panel
            height = app.PanelHeight*2; % Height of the panel
            app.titles{n} = sprintf('Load_%d', app.PanelCount);
            % Create the new panel
            newPanel = uipanel(app.LoadsPanel, ...
                'Title', app.titles{n}, ...
                'Position', [x, y, width, height]);
            app.allpanels.(app.titles{n}) = newPanel;
            app.isloadadded.(app.titles{n}) = false;
            
            prevButton = uibutton(newPanel,"Text","Preview",'Position', [330 100 60 30]);
            prevButton.ButtonPushedFcn = {@app.prevMethod,newPanel};

            AddButton = uibutton(newPanel,"Text","Add",'Position', [330 60 60 30],'BackgroundColor','g');
            AddButton.ButtonPushedFcn = {@app.AddMethod,newPanel};

            deleteButton = uibutton(newPanel,"Text","Delete",'Position', [330 20 60 30],'BackgroundColor','r');
            deleteButton.ButtonPushedFcn = {@app.deleteMethodtest,newPanel};
             
            % Create a dropdown menu inside the panel
            dropdown = uidropdown(newPanel, ...
                'Items', {'Conc Force', 'Conc Moment','Distributed Load'}, ...
                'Position', [10, 100, 130, 30], ...
                'ValueChangedFcn', @(dd, event) DropdownValueChanged(app, dd, newPanel));
            dropdown3 = uidropdown(newPanel, ...
                'Items', {'Y-Z','X-Z'}, ...
                'Position', [240, 100, 60, 30]);
            dropdown2 = uidropdown(newPanel, ...
                'Items', {'down','up'}, ...
                'Position', [150, 100, 80, 30]);

            fieldWidth = 50; % Width of each text field
            fieldSpacing = 10; % Space between text fields
            startX = 20; % Starting X position inside the panel

            position1label = uilabel(newPanel,"Text","Position 1",...
                            "Position",[startX, 60, fieldWidth+35, 30],...
                            "FontSize",12);
            position1val = uieditfield(newPanel, 'numeric', ...
                            'Position', [startX +  1.2*(fieldWidth + fieldSpacing), 60, fieldWidth, 30], ...
                            'AllowEmpty','off',...
                            'Limits',[0 double(eb./L)]);
            
            position2label = uilabel(newPanel,"Text","Position 2",...
                             "Position",[startX+  2*1.2*(fieldWidth + fieldSpacing), 60, fieldWidth+35, 30],...
                             "FontSize",12);
            position2val = uieditfield(newPanel, 'numeric', ...
                            'Position', [startX + 3 * 1.2*(fieldWidth + fieldSpacing), 60, fieldWidth, 30], ...
                            'AllowEmpty','off',...
                            'Limits',[0 double(eb./L)]);
            mag1label = uilabel(newPanel,"Text","Magnitude 1",...
                             "Position",[startX, 20, fieldWidth+35, 30],...
                             "FontSize",12);
            mag1val = uieditfield(newPanel, 'numeric', ...
                            'Position', [startX +  1.2*(fieldWidth + fieldSpacing), 20, fieldWidth, 30], ...
                            'AllowEmpty','off',...
                            'Limits',[0 inf]);
            mag2label = uilabel(newPanel,"Text","Magnitude 2",...
                             "Position",[startX+  2*1.2*(fieldWidth + fieldSpacing), 20, fieldWidth+35, 30],...
                             "FontSize",12);
            mag2val = uieditfield(newPanel, 'numeric', ...
                            'Position', [startX + 3 * 1.2*(fieldWidth + fieldSpacing), 20, fieldWidth, 30], ...
                            'AllowEmpty','off',...
                            'Limits',[0 inf]);


            % Trigger the dropdown callback to initialize the default text fields
            DropdownValueChanged(app, dropdown, newPanel);
        else
            uialert(app.UIFigure,"First declare the Beam Length and Click Begin","NOT SO FAST","Interpreter","latex")
        end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            delete(app.LoadsPanel.Children)
            delete(app.allplotsload);
            app.allplotsload = [];
            if app.prevstart
                delete(app.preview.pl)
            end
            app.PanelCount = 0;
            app.titles = {};
            
            
            if app.isloadpanel
                if any(cell2mat(struct2cell(app.isloadadded)))
                    v2 = fieldnames(app.allloads);
                    app.allloads = rmfield(app.allloads,v2);
                end
                v1 = fieldnames(app.allpanels);
                app.allpanels = rmfield(app.allpanels,v1);
                v3 = fieldnames(app.isloadadded);
                app.isloadadded = rmfield(app.isloadadded,v3);
            end
            app.isloadpanel = false;
        end

        % Button pushed function: NewSupportButton
        function NewSupportButtonPushed(app, event)
        if app.begin
            % Increment the panel count
            global eb; syms L; app.issupppanel = true;
            app.PanelCount_2 = app.PanelCount_2 + 1;
            n = app.PanelCount_2;
            % Calculate the position for the new panel
            x = 10; % Left margin
            y = -90 + app.PanelCount_2 * (app.PanelHeight*1.5 + app.PanelSpacing); % Dynamic vertical position
            width = 310; % Width of the panel
            height = app.PanelHeight*1.5; % Height of the panel
            app.titles2{n} = sprintf('Support_%d', app.PanelCount_2);
            % Create the new panel
            newPanel = uipanel(app.SupportsPanel, ...
                'Title', app.titles2{n}, ...
                'Position', [x, y, width, height]);
            app.allpanels2.(app.titles2{n}) = newPanel;
            app.issuppadded.(app.titles2{n}) = false;

            AddButton = uibutton(newPanel,"Text","Add",'Position', [240 60 60 30],'BackgroundColor','g');
            AddButton.ButtonPushedFcn = {@app.AddMethod2,newPanel};

            deleteButton = uibutton(newPanel,"Text","Delete",'Position', [240 20 60 30],'BackgroundColor','r');
            deleteButton.ButtonPushedFcn = {@app.deleteMethod3,newPanel};
            % Create a dropdown menu inside the panel
            dropdown = uidropdown(newPanel, ...
                'Items', {'Fixed', 'Simple','Roller'}, ...
                'Position', [15, 60, 70, 30], ...
                'ValueChangedFcn', @(ddd, event) DropdownValueChanged_S1(app, ddd));

            fieldWidth = 30; % Width of each text field
            fieldSpacing = 10; % Space between text fields
            startX = 90; % Starting X position inside the panel
            uilabel(newPanel,"Text","Position",...
                "Position",[15, 20, fieldWidth+35, 30],...
                "FontSize",14);
            uieditfield(newPanel, 'numeric', ...
                'Position', [(30+fieldWidth + fieldSpacing), 20, fieldWidth*1.5, 30], ...
                'AllowEmpty','off',...
                'Limits',[0 double(eb./L)]);
            uilabel(newPanel,"Text","Plane",...
                "Position",[startX + 20, 60, fieldWidth+20, 30],...
                "FontSize",14);
            dropdown2 = uidropdown(newPanel, ...
                'Items', {'Y-Z', 'X-Z'}, ...
                'Position', [startX+ 10+ (10+fieldWidth + fieldSpacing), 60, 60, 30]);

            % Trigger the dropdown callback to initialize the default text fields
            DropdownValueChanged_S1(app, dropdown);
        else
            uialert(app.UIFigure,"First declare the Beam Length and Click Begin","NOT SO FAST","Interpreter","latex")
        end
        end

        % Button pushed function: ClearAllButton_2
        function ClearAllButton_2Pushed(app, event)
            global o;
            o = 1;
            delete(app.SupportsPanel.Children)
            delete(app.allplotssupp);
            app.allplotssupp = [];
            app.titles2 = {};
            
            
            if app.issupppanel
                if any(cell2mat(struct2cell(app.issuppadded)))
                    v2 = fieldnames(app.allsupps);
                    app.allsupps = rmfield(app.allsupps,v2);
                end
                v1 = fieldnames(app.allpanels2);
                app.allpanels2 = rmfield(app.allpanels2,v1);
                v3 = fieldnames(app.issuppadded);
                app.issuppadded = rmfield(app.issuppadded,v3);
                app.PanelCount_2 = 0;
            end
            app.issupppanel = false;
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            global eb w Is o; syms L;
            o = 1 ;
            if app.begin 
                ClearAllButton_2Pushed(app,event)
                ClearAllButtonPushed(app,event)
                delete(app.allbeamplots)
                delete(app.solvedplots)
            end
            eb = app.BeamLengthEditField.Value*L;
            app.allbeamplots = initial_plot();
            app.begin = true;
            app.TabGroup.SelectedTab = app.ProblemInputTab;
            if isempty(w)
                Is = [];
            else
                Is1 = (app.I_xK_xEditField.Value)/(app.I_xK_xEditField_2.Value);
                Is2 = (app.I_yK_yEditField.Value)/(app.I_xK_xEditField_3.Value);
                Is3 = (app.I_xyK_xyEditField.Value)/(app.I_xK_xEditField_4.Value);
                Is = [Is1,Is2,Is3];
            end
            app.UIAxes.XTickMode = "auto";
            app.UIAxes.XTickLabelMode = "auto";
            app.UIAxes2.XTickMode = "auto";
            app.UIAxes2.XTickLabelMode = "auto";
            app.UIAxes3.XTickMode = "auto";
            app.UIAxes3.XTickLabelMode = "auto";
            app.UIAxes4.XTickMode = "auto";
            app.UIAxes4.XTickLabelMode = "auto";
            app.UIAxes.YTickMode = "auto";
            app.UIAxes.YTickLabelMode = "auto";
            app.UIAxes2.YTickMode = "auto";
            app.UIAxes2.YTickLabelMode = "auto";
            app.UIAxes3.YTickMode = "auto";
            app.UIAxes3.YTickLabelMode = "auto";
            app.UIAxes4.YTickMode = "auto";
            app.UIAxes4.YTickLabelMode = "auto";

            val = app.BeamLengthEditField.Value;
            xlims = [-1,3]*val;
            ylims = xlims;

            app.UIAxes.XLim = xlims;
            app.UIAxes.YLim = ylims;
            app.UIAxes2.XLim = xlims;
            app.UIAxes2.YLim = ylims;
            app.UIAxes3.XLim = xlims;
            app.UIAxes3.YLim = ylims;
            app.UIAxes4.XLim = xlims;
            app.UIAxes4.YLim = ylims;

            ticks1 = (-0.8:0.4:2.8)*val;
            ticks2 = ticks1;

            app.UIAxes.XTick = ticks1;
            app.UIAxes.YTick = ticks2;
            app.UIAxes2.XTick = ticks1;
            app.UIAxes2.YTick = ticks2;
            app.UIAxes3.XTick = ticks1;
            app.UIAxes3.YTick = ticks2;
            app.UIAxes4.XTick = ticks1;
            app.UIAxes4.YTick = ticks2;

            symticks = ticks1./2 + "L";

            app.UIAxes.XTickLabel = symticks;
            app.UIAxes2.XTickLabel = symticks;
            app.UIAxes3.XTickLabel = symticks;
            app.UIAxes4.XTickLabel = symticks;

            
        end

        % Selection changed function: InertiaoptionsButtonGroup
        function InertiaoptionsButtonGroupSelectionChanged(app, event)
            global w;
            selectedButton = app.InertiaoptionsButtonGroup.SelectedObject;
            t = selectedButton.Text;
            if strcmpi(t , "sympolically")
                app.I_xK_xEditField.Editable = "off";
                app.I_yK_yEditField.Editable = "off";
                app.I_xyK_xyEditField.Editable = "off";
                w = [];
            elseif strcmpi(t , "using Kx , Ky , Kxy")
                app.I_xK_xEditField.Editable = "on";
                app.I_yK_yEditField.Editable = "on";
                app.I_xyK_xyEditField.Editable = "on";
                w = "K";
            elseif strcmpi(t , "using Ix , Iy , Ixy")
                app.I_xK_xEditField.Editable = "on";
                app.I_yK_yEditField.Editable = "on";
                app.I_xyK_xyEditField.Editable = "on";
                w = "I";
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            if app.issupppanel && app.isloadpanel
                [app.Mx,app.My,app.Sx,app.Sy,app.v,app.v_dash,app.u,app.u_dash,app.solutions,isyz,isxz] = Main_Solver(struct2cell(app.allsupps)',struct2cell(app.allloads)');
                if app.issolved
                    delete(app.solvedplots)
                end
                [pl1,data] = final_plots(app.Mx,app.My,app.Sx,app.Sy,app.v,app.u,isyz,isxz,app.v_dash,app.u_dash); 
                pl2 = solplot(app.UIAxes11,app.solutions);
                S.M_x = app.Mx;
                S.M_y = app.My;
                S.v = app.v;
                S.u = app.u;
                pl3 = momentdisp(app.UIAxes12,S);
                app.solvedplots = [pl1 pl2 pl3];
                app.UITable.Data = double(data);
                app.issolved = true;
            else
                uialert(app.UIFigure,"Please add at least 1 support and 1 Load","Can't Proceed","Interpreter","latex")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitialInputsTab = uitab(app.TabGroup);
            app.InitialInputsTab.Title = 'Initial Inputs';
            app.BeginButton = uibutton(app.InitialInputsTab, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.BeamLengthEditFieldLabel = uilabel(app.InitialInputsTab);
            app.BeamLengthEditFieldLabel.Interpreter = 'latex';
            app.BeamLengthEditFieldLabel.Text = 'Beam Length';
            app.BeamLengthEditField = uieditfield(app.InitialInputsTab, 'numeric');
            app.BeamLengthEditField.Limits = [1 Inf];
            app.BeamLengthEditField.Value = 1;
            app.InertiaoptionsButtonGroup = uibuttongroup(app.InitialInputsTab);
            app.InertiaoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InertiaoptionsButtonGroupSelectionChanged, true);
            app.InertiaoptionsButtonGroup.Title = 'Inertia options';
            app.SymbolicallyButton = uiradiobutton(app.InertiaoptionsButtonGroup);
            app.SymbolicallyButton.Text = 'Symbolically';
            app.SymbolicallyButton.Value = true;
            app.UsingIxIyIxyButton = uiradiobutton(app.InertiaoptionsButtonGroup);
            app.UsingIxIyIxyButton.Text = 'Using Ix , Iy , Ixy';
            app.UsingKxKyKxyButton = uiradiobutton(app.InertiaoptionsButtonGroup);
            app.UsingKxKyKxyButton.Text = 'Using Kx , Ky , Kxy';
            app.I_xK_xLabel = uilabel(app.InitialInputsTab);
            app.I_xK_xLabel.Interpreter = 'latex';
            app.I_xK_xLabel.Text = '$I_x$  $||$  $K_x$';
            app.I_xK_xEditField = uieditfield(app.InitialInputsTab, 'numeric');
            app.I_xK_xEditField.RoundFractionalValues = 'on';
            app.I_xK_xEditField.Editable = 'off';
            app.I_yLabel = uilabel(app.InitialInputsTab);
            app.I_yLabel.Interpreter = 'latex';
            app.I_yLabel.Text = '$I_y$  $||$  $K_y$';
            app.I_yK_yEditField = uieditfield(app.InitialInputsTab, 'numeric');
            app.I_yK_yEditField.RoundFractionalValues = 'on';
            app.I_yK_yEditField.Editable = 'off';
            app.I_xyK_xyEditFieldLabel = uilabel(app.InitialInputsTab);
            app.I_xyK_xyEditFieldLabel.Interpreter = 'latex';
            app.I_xyK_xyEditFieldLabel.Tag = 'ixylabel';
            app.I_xyK_xyEditFieldLabel.Text = '$I_{xy}$  $||$  $K_{xy}$';
            app.I_xyK_xyEditField = uieditfield(app.InitialInputsTab, 'numeric');
            app.I_xyK_xyEditField.RoundFractionalValues = 'on';
            app.I_xyK_xyEditField.Editable = 'off';
            app.I_xK_xEditField_2 = uieditfield(app.InitialInputsTab, 'numeric');
            app.I_xK_xEditField_2.Limits = [1 Inf];
            app.I_xK_xEditField_2.RoundFractionalValues = 'on';
            app.I_xK_xEditField_2.Value = 1;
            app.Label = uilabel(app.InitialInputsTab);
            app.Label.Text = '/';
            app.I_xK_xEditField_3 = uieditfield(app.InitialInputsTab, 'numeric');
            app.I_xK_xEditField_3.Limits = [1 Inf];
            app.I_xK_xEditField_3.RoundFractionalValues = 'on';
            app.I_xK_xEditField_3.Value = 1;
            app.Label_2 = uilabel(app.InitialInputsTab);
            app.Label_2.Text = '/';
            app.I_xK_xEditField_4 = uieditfield(app.InitialInputsTab, 'numeric');
            app.I_xK_xEditField_4.Limits = [1 Inf];
            app.I_xK_xEditField_4.RoundFractionalValues = 'on';
            app.I_xK_xEditField_4.Value = 1;
            app.Label_3 = uilabel(app.InitialInputsTab);
            app.Label_3.Text = '/';
            app.EquationsLabel = uilabel(app.InitialInputsTab);
            app.EquationsLabel.Interpreter = 'latex';
            app.EquationsLabel.Text = {'Equations:'; '\['; 'E\ u'''' \;= K{xy}\,M{x} \;-\; K{x}\,M{y} , \quad E\ v'''' \;= -K{y}\,Mx \;+\; K{xy}\,My'; '\]'};
            app.c1istheintegrationconstantinthefirstintegralofMxLabel = uilabel(app.InitialInputsTab);
            app.c1istheintegrationconstantinthefirstintegralofMxLabel.Text = {'c1 is the integration constant in the first integral of Mx'; 'c2 is the integration constant in the second integral of Mx'; 'c3 is the integration constant in the first integral of My'; 'c4 is the integration constant in the second integral of My'};
            app.ProblemInputTab = uitab(app.TabGroup);
            app.ProblemInputTab.Title = 'Problem Input';
            app.Panel = uipanel(app.ProblemInputTab);
            app.LoadsPanel = uipanel(app.Panel);
            app.LoadsPanel.Title = 'Loads';
            app.NewLoadButton = uibutton(app.Panel, 'push');
            app.NewLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NewLoadButtonPushed, true);
            app.NewLoadButton.Text = 'New Load';
            app.ClearAllButton = uibutton(app.Panel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.Panel_2 = uipanel(app.ProblemInputTab);
            app.SupportsPanel = uipanel(app.Panel_2);
            app.SupportsPanel.Title = 'Supports';
            app.NewSupportButton = uibutton(app.Panel_2, 'push');
            app.NewSupportButton.ButtonPushedFcn = createCallbackFcn(app, @NewSupportButtonPushed, true);
            app.NewSupportButton.Text = 'New Support';
            app.ClearAllButton_2 = uibutton(app.Panel_2, 'push');
            app.ClearAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_2Pushed, true);
            app.ClearAllButton_2.Text = 'Clear All';
            app.Panel_3 = uipanel(app.ProblemInputTab);
            app.GridLayout = uigridlayout(app.Panel_3);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'y-z inputs')
            xlabel(app.UIAxes, 'Z')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 10];
            app.UIAxes.YLim = [-2 10];
            app.UIAxes.XTick = [-2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'-1'; '-0.5'; '0'; '0.5L'; 'L'; '1.5 L'; '2 L'; '2.5 L'; '3 L'; '3.5 L'; '4 L'; '4.5 L'; '5 L'};
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'x-z inputs')
            xlabel(app.UIAxes2, 'Z')
            ylabel(app.UIAxes2, 'X')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-2 10];
            app.UIAxes2.YLim = [-2 10];
            app.UIAxes2.XTick = [-2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes2.XTickLabel = {'-1'; '-0.5'; '0'; '0.5L'; 'L'; '1.5 L'; '2 L'; '2.5 L'; '3 L'; '3.5 L'; '4 L'; '4.5 L'; '5 L'};
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Preview')
            xlabel(app.UIAxes3, 'z')
            ylabel(app.UIAxes3, 'Y or X')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [-2 10];
            app.UIAxes3.YLim = [-2 10];
            app.UIAxes3.XTick = [-2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes3.XTickLabel = {'-1'; '-0.5'; '0'; '0.5L'; 'L'; '1.5 L'; '2 L'; '2.5 L'; '3 L'; '3.5 L'; '4 L'; '4.5 L'; '5 L'};
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Correction Preview')
            xlabel(app.UIAxes4, 'Z')
            ylabel(app.UIAxes4, 'Y or X')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XLim = [-2 10];
            app.UIAxes4.YLim = [-2 10];
            app.UIAxes4.XTick = [-2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes4.XTickLabel = {'-1'; '-0.5'; '0'; '0.5L'; 'L'; '1.5 L'; '2 L'; '2.5 L'; '3 L'; '3.5 L'; '4 L'; '4.5 L'; '5 L'};
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.GridLayout2 = uigridlayout(app.GraphsTab);
            app.UIAxes5 = uiaxes(app.GridLayout2);
            title(app.UIAxes5, 'S_y')
            xlabel(app.UIAxes5, 'Z')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes10 = uiaxes(app.GridLayout2);
            title(app.UIAxes10, 'U')
            xlabel(app.UIAxes10, 'Z')
            ylabel(app.UIAxes10, 'X')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes9 = uiaxes(app.GridLayout2);
            title(app.UIAxes9, 'M_y')
            xlabel(app.UIAxes9, 'Z')
            ylabel(app.UIAxes9, 'X')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes8 = uiaxes(app.GridLayout2);
            title(app.UIAxes8, 'S_x')
            xlabel(app.UIAxes8, 'Z')
            ylabel(app.UIAxes8, 'X')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes7 = uiaxes(app.GridLayout2);
            title(app.UIAxes7, 'V')
            xlabel(app.UIAxes7, 'Z')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout2);
            title(app.UIAxes6, 'M_x')
            xlabel(app.UIAxes6, 'Z')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.SolveButton = uibutton(app.GridLayout2, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.YZPlaneLabel = uilabel(app.GridLayout2);
            app.YZPlaneLabel.Text = 'Y-Z Plane';
            app.XZPlaneLabel = uilabel(app.GridLayout2);
            app.XZPlaneLabel.Text = 'X-Z Plane';
            app.ShearLabel = uilabel(app.GridLayout2);
            app.ShearLabel.Text = 'Shear';
            app.MomentLabel = uilabel(app.GridLayout2);
            app.MomentLabel.Text = 'Moment';
            app.DeflectionLabel = uilabel(app.GridLayout2);
            app.DeflectionLabel.Text = 'Deflection';
            app.EquationsTab = uitab(app.TabGroup);
            app.EquationsTab.Title = 'Equations';
            app.UIAxes11 = uiaxes(app.EquationsTab);
            title(app.UIAxes11, 'UNKOWNS')
            app.UIAxes11.XLim = [0 20];
            app.UIAxes11.YLim = [0 20];
            app.UIAxes11.XTick = [];
            app.UIAxes11.YTick = [];
            app.UIAxes12 = uiaxes(app.EquationsTab);
            title(app.UIAxes12, 'Equations')
            app.UIAxes12.XLim = [0 20];
            app.UIAxes12.YLim = [0 20];
            app.UIAxes12.XTick = [];
            app.UIAxes12.YTick = [];
            app.UIAxes12.ZTick = [];
            app.CustomCalculatorTab = uitab(app.TabGroup);
            app.CustomCalculatorTab.Title = 'Custom Calculator';
            app.UITable = uitable(app.CustomCalculatorTab);
            app.UITable.ColumnName = {'Z/L'; 'v'; 'v'''; 'u'; 'u'''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end